classdef ArchiveWalkerRTD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ProjectPathEditFieldLabel  matlab.ui.control.Label
        ProjectPathEditField       matlab.ui.control.EditField
        ProjectDropDownLabel       matlab.ui.control.Label
        ProjectDropDown            matlab.ui.control.DropDown
        TaskDropDownLabel          matlab.ui.control.Label
        TaskDropDown               matlab.ui.control.DropDown
        RunButton                  matlab.ui.control.StateButton
        TabGroup                   matlab.ui.container.TabGroup
        ForcedOscillationsTab      matlab.ui.container.Tab
        FOplot                     matlab.ui.control.UIAxes
        OutofRangeEventsTab        matlab.ui.container.Tab
        OORtabs                    matlab.ui.container.TabGroup
        OORtab1                    matlab.ui.container.Tab
        OORplot_T1P1               matlab.ui.control.UIAxes
        OORtab2                    matlab.ui.container.Tab
        OORplot_T2P1               matlab.ui.control.UIAxes
        OORplot_T2P2               matlab.ui.control.UIAxes
        OORtab3                    matlab.ui.container.Tab
        OORplot_T3P1               matlab.ui.control.UIAxes
        OORplot_T3P2               matlab.ui.control.UIAxes
        OORplot_T3P3               matlab.ui.control.UIAxes
        RingdownsTab               matlab.ui.container.Tab
        RINGtabs                   matlab.ui.container.TabGroup
        RINGtab1                   matlab.ui.container.Tab
        RINGplot_T1P1              matlab.ui.control.UIAxes
        DetectorsTab2              matlab.ui.container.Tab
        RINGplot_T2P1              matlab.ui.control.UIAxes
        RINGplot_T2P2              matlab.ui.control.UIAxes
        RINGtab3                   matlab.ui.container.Tab
        RINGplot_T3P1              matlab.ui.control.UIAxes
        RINGplot_T3P2              matlab.ui.control.UIAxes
        RINGplot_T3P3              matlab.ui.control.UIAxes
        AllEventsTab               matlab.ui.container.Tab
        ALLplot                    matlab.ui.control.UIAxes
        WindRampsTab               matlab.ui.container.Tab
        WINDtabs                   matlab.ui.container.TabGroup
        WINDtab1                   matlab.ui.container.Tab
        WINDplot_T1P1              matlab.ui.control.UIAxes
        WINDtab2                   matlab.ui.container.Tab
        WINDplot_T2P1              matlab.ui.control.UIAxes
        WINDplot_T2P2              matlab.ui.control.UIAxes
        WINDtab3                   matlab.ui.container.Tab
        WINDplot_T3P1              matlab.ui.control.UIAxes
        WINDplot_T3P2              matlab.ui.control.UIAxes
        WINDplot_T3P3              matlab.ui.control.UIAxes
        ModeMeterTab               matlab.ui.container.Tab
        Mode2DropDownLabel         matlab.ui.control.Label
        Mode2DropDown              matlab.ui.control.DropDown
        ModeMeter1DropDownLabel    matlab.ui.control.Label
        ModeMeter1DropDown         matlab.ui.control.DropDown
        ModeMeter2DropDownLabel    matlab.ui.control.Label
        ModeMeter2DropDown         matlab.ui.control.DropDown
        Algorithm1DropDownLabel    matlab.ui.control.Label
        Algorithm1DropDown         matlab.ui.control.DropDown
        Algorithm2DropDownLabel    matlab.ui.control.Label
        Algorithm2DropDown         matlab.ui.control.DropDown
        Mode1DropDownLabel         matlab.ui.control.Label
        Mode1DropDown              matlab.ui.control.DropDown
        Damp1                      matlab.ui.control.UIAxes
        Freq1                      matlab.ui.control.UIAxes
        Damp2                      matlab.ui.control.UIAxes
        Freq2                      matlab.ui.control.UIAxes
        MMallPlot1                 matlab.ui.control.UIAxes
        MMallPlot2                 matlab.ui.control.UIAxes
        WindowminDropDownLabel     matlab.ui.control.Label
        WindowminDropDown          matlab.ui.control.DropDown
        TimeDropDownLabel          matlab.ui.control.Label
        TimeDropDown               matlab.ui.control.DropDown
    end


    methods (Access = private)
    
        function ModeList = RetrieveModeList(app,MMselection)
            ProjectPath = app.ProjectPathEditField.Value;
            Project = ['Project_' app.ProjectDropDown.Value];
            Task = ['Task_' app.TaskDropDown.Value];
            
            ConfigFile = fullfile(ProjectPath,Project,Task,'Config.xml');
            
            try
                ConfigAll = fun_xmlread_comments(ConfigFile);
                DetectorXML = ConfigAll.Config.DetectorConfig.Configuration;
                if isfield(DetectorXML,'ModeMeter')
                    if length(DetectorXML.ModeMeter) == 1
                        MMconfig = {DetectorXML.ModeMeter};
                    else
                        MMconfig = DetectorXML.ModeMeter;
                    end
                    
                    MMlist = cell(1,length(MMconfig));
                    for MMidx = 1:length(MMconfig)
                        MMlist{MMidx} = MMconfig{MMidx}.ResultPath(5:end);
                    end
                    
                    MMidx = strcmp(MMlist,MMselection);
                    Modes = MMconfig{MMidx}.Mode;
                    if length(Modes) == 1
                        Modes = {Modes};
                    end
                    
                    ModeList = cell(1,length(Modes));
                    for Midx = 1:length(Modes)
                        ModeList{Midx} = Modes{Midx}.Name;
                    end
                    
                    if length(ModeList) == 1
                        ModeList = ['Select' ModeList];
                    end
                else
                    ModeList = {'No Mode Meters'};
                end
            catch
                ModeList = {'Invalid Project Path'};
            end
        end
        
        function AlgList = RetrieveAlgList(app,MMselection,ModeSelection)
            ProjectPath = app.ProjectPathEditField.Value;
            Project = ['Project_' app.ProjectDropDown.Value];
            Task = ['Task_' app.TaskDropDown.Value];
            
            ConfigFile = fullfile(ProjectPath,Project,Task,'Config.xml');
            
            try
                ConfigAll = fun_xmlread_comments(ConfigFile);
                DetectorXML = ConfigAll.Config.DetectorConfig.Configuration;
                if isfield(DetectorXML,'ModeMeter')
                    if length(DetectorXML.ModeMeter) == 1
                        MMconfig = {DetectorXML.ModeMeter};
                    else
                        MMconfig = DetectorXML.ModeMeter;
                    end
                    
                    MMlist = cell(1,length(MMconfig));
                    for MMidx = 1:length(MMconfig)
                        MMlist{MMidx} = MMconfig{MMidx}.ResultPath(5:end);
                    end
                    
                    MMidx = strcmp(MMlist,MMselection);
                    Modes = MMconfig{MMidx}.Mode;
                    if length(Modes) == 1
                        Modes = {Modes};
                    end
                    
                    ModeList = cell(1,length(Modes));
                    for Midx = 1:length(Modes)
                        ModeList{Midx} = Modes{Midx}.Name;
                    end
                    
                    Midx = strcmp(ModeList,ModeSelection);
                    Algs = Modes{Midx}.AlgNames;
                    if length(Algs) == 1
                        Algs = {Algs};
                    end
                    
                    AlgList = cell(1,length(Algs));
                    for Aidx = 1:length(Algs)
                        AlgList{Aidx} = Algs{Aidx}.Name;
                    end
                    
                    if length(AlgList) == 1
                        AlgList = ['Select' AlgList];
                    end
                else
                    AlgList = {'No Mode Meters'};
                end
            catch
                AlgList = {'Invalid Project Path'};
            end
        end
        
        
        function DesiredMode = RetrieveDesiredMode(app,MMselection,ModeSelection)
            ProjectPath = app.ProjectPathEditField.Value;
            Project = ['Project_' app.ProjectDropDown.Value];
            Task = ['Task_' app.TaskDropDown.Value];
            
            ConfigFile = fullfile(ProjectPath,Project,Task,'Config.xml');
            
            try
                ConfigAll = fun_xmlread_comments(ConfigFile);
                DetectorXML = ConfigAll.Config.DetectorConfig.Configuration;
                if isfield(DetectorXML,'ModeMeter')
                    if length(DetectorXML.ModeMeter) == 1
                        MMconfig = {DetectorXML.ModeMeter};
                    else
                        MMconfig = DetectorXML.ModeMeter;
                    end
                    
                    MMlist = cell(1,length(MMconfig));
                    for MMidx = 1:length(MMconfig)
                        MMlist{MMidx} = MMconfig{MMidx}.ResultPath(5:end);
                    end
                    
                    MMidx = strcmp(MMlist,MMselection);
                    Modes = MMconfig{MMidx}.Mode;
                    if length(Modes) == 1
                        Modes = {Modes};
                    end
                    
                    ModeList = cell(1,length(Modes));
                    for Midx = 1:length(Modes)
                        ModeList{Midx} = Modes{Midx}.Name;
                    end
                    
                    Midx = strcmp(ModeList,ModeSelection);
                    DesiredMode = Modes{Midx}.DesiredModes;
                else
                    DesiredMode = [];
                end
            catch
                DesiredMode = [];
            end
        end
        
        function EL = LoadEventLists(app,tStart,tNow,EventPath,Detector)
            if exist(fullfile(EventPath,'EventList_Current.XML'),'file')
                ELcurrent = EventListXML2MAT(fun_xmlread_comments(fullfile(EventPath,'EventList_Current.XML')));
                % Set to empty if the file was completely empty or if there were no Out-of-Range events
                if isstruct(ELcurrent)
                    if isfield(ELcurrent,Detector)
                        ELcurrent = ELcurrent.(Detector);
                    else
                        ELcurrent = [];
                    end
                else
                    ELcurrent = [];
                end
            else
                ELcurrent = [];
            end
            %
            if exist(fullfile(EventPath,['EventList_' datestr(tNow,'yymmdd') '.XML']),'file')
                ELtoday = EventListXML2MAT(fun_xmlread_comments(fullfile(EventPath,['EventList_' datestr(tNow,'yymmdd') '.XML'])));
                % Set to empty if the file was completely empty or if there were no Out-of-Range events
                if isstruct(ELtoday)
                    if isfield(ELtoday,Detector)
                        ELtoday = ELtoday.(Detector);
                    else
                        ELtoday = [];
                    end
                else
                    ELtoday = [];
                end
            else
                ELtoday = [];
            end
            %
            if day(tStart) ~= day(tNow)
                % Display window crosses days, load yesterday's event file
                if exist(fullfile(EventPath,['EventList_' datestr(tStart,'yymmdd') '.XML']),'file')
                    ELyesterday = EventListXML2MAT(fun_xmlread_comments(fullfile(EventPath,['EventList_' datestr(tStart,'yymmdd') '.XML'])));
                    % Set to empty if the file was completely empty or if there were no Out-of-Range events
                    if isstruct(ELyesterday)
                        if isfield(ELyesterday,Detector)
                            ELyesterday = ELyesterday.(Detector);
                        else
                            ELyesterday = [];
                        end
                    else
                        ELyesterday = [];
                    end
                else
                    ELyesterday = [];
                end
            else
                % Display window does not cross days, no need to load yesterday's event file
                ELyesterday = [];
            end
            
            % Combine current, today's, and yesterday's
            EL = [ELcurrent ELtoday ELyesterday];
            
            % If any events were found, capture their ends
            if isstruct(EL)
                if strcmp(Detector,'WindRamp')
                    % Special case for wind ramp
                    Eends = datenum({EL.TrendEnd});
                elseif strcmp(Detector,'ForcedOscillation')
                    % Special case for forced oscillations
                    Eends = zeros(1,length(EL));
                    for Eidx = 1:length(EL)
                        Eends(Eidx) = max(EL(Eidx).End);
                    end
                else
                    Eends = [EL.End];
                end
            else
                Eends = [];
            end
            % Remove those ending before the display window
            KillIdx = Eends < datenum(tStart);
            EL(KillIdx) = [];
        end
        
        function GenRingOORplots(app,PlotPointers,SparseOut,PlotClrs,EL,tStart,tNow)
            
            for idx = 1:min([length(SparseOut) length(PlotPointers)])
                tPlot = datetime(SparseOut(idx).t,'ConvertFrom','datenum');
                tt = NaT(2*length(tPlot),1);
                tt(1) = tPlot(1)-1/60/24;
                tt(3:2:end) = tPlot(1:end-1);
                tt(2:2:end) = tPlot;
                tPlot = [tt; tt(end:-1:1)];
                tPlot.TimeZone = tStart.TimeZone;
                for cidx = 1:size(SparseOut(idx).DataMin,2)
                    y1 = SparseOut(idx).DataMin(:,cidx);
                    yy1 = zeros(2*length(y1),1);
                    yy1(1:2:end) = y1;
                    yy1(2:2:end) = y1;
                    
                    y2 = SparseOut(idx).DataMax(:,cidx);
                    yy2 = zeros(2*length(y2),1);
                    yy2(1:2:end) = y2;
                    yy2(2:2:end) = y2;
                    
                    y = [yy1; yy2(end:-1:1)];
                    
                    ClrIdx = 1+mod(cidx,size(PlotClrs,1));
                    fill(PlotPointers{idx},tPlot,y,PlotClrs(ClrIdx,:),'FaceAlpha',0.5,'EdgeColor',PlotClrs(ClrIdx,:),'LineWidth',3)
                    ylim(PlotPointers{idx},'auto');
                    
                    if cidx == 1
                        hold(PlotPointers{idx})
                    end
                end
                Units = unique(SparseOut(idx).DataUnit);
                if length(Units) == 1
                    Units = Units{1};
                else
                    Units = 'Mixed';
                end
                 
                
                % Combo PMU/channel names for signals in this plot
                PMUchanPlot = strcat(SparseOut(idx).DataPMU, SparseOut(idx).DataChannel);
                
                % Iterate through the event list. If any of the signals in the plot were included in the event, indicate the event on the plot
                for ELidx = 1:length(EL)
                    PMUchanEL = strcat(EL(ELidx).PMU,EL(ELidx).Channel);
                    
                    if isempty(intersect(PMUchanPlot,PMUchanEL))
                        continue;
                    end
                    
                    % Add the event to the plot
                    YL = ylim(PlotPointers{idx});
                    y = [YL(1) YL(2) YL(2) YL(1)];
                    tPlot = datetime([EL(ELidx).Start EL(ELidx).Start EL(ELidx).End EL(ELidx).End],'ConvertFrom','datenum');
                    tPlot.TimeZone = tStart.TimeZone;
                    fill(PlotPointers{idx},tPlot,y,'r','FaceAlpha',0.3,'EdgeColor','r','LineWidth',3)
                    ylim(PlotPointers{idx},YL);
                end
                
                ylabel(PlotPointers{idx},Units);
                xlim(PlotPointers{idx},[tStart tNow]);
                legend(PlotPointers{idx},SparseOut(idx).DataChannel,'Location','northwest','interpreter','none');
                hold(PlotPointers{idx})
            end
        end

        function [St,En,Freq,A] = RetrieveFOinfo(app,EL)
            St = NaT(0);
            En = NaT(0);
            Freq = [];
            A = [];
            for Eidx = 1:length(EL)
                for OccIdx = 1:length(EL(Eidx).Start)
                    St = [St datetime(EL(Eidx).Start(OccIdx),'ConvertFrom','datenum')];
                    En = [En datetime(EL(Eidx).End(OccIdx),'ConvertFrom','datenum')];
                    Freq = [Freq EL(Eidx).Frequency(OccIdx)];
                    A = [A EL(Eidx).AlarmFlag(OccIdx)];
                end
            end
        end
        
        function AddEventsToALLplot(app,tStart,EL,PlotPointer,Clr)
            for ELidx = 1:length(EL)
                % Add the event to the plot
                YL = ylim(PlotPointer);
                y = [YL(1) YL(2) YL(2) YL(1)];
                tPlot = datetime([EL(ELidx).Start EL(ELidx).Start EL(ELidx).End EL(ELidx).End],'ConvertFrom','datenum');
                tPlot.TimeZone = tStart.TimeZone;
                fill(PlotPointer,tPlot,y,Clr,'FaceAlpha',0.3,'EdgeColor',Clr,'LineWidth',3)
                ylim(PlotPointer,YL);
            end
        end
        
        function GenAllplot(app,tStart,tNow,EventPath,PlotPointer,DesiredMode)
            EL = LoadEventLists(app,tStart,tNow,EventPath,'ForcedOscillation');
            [St,En,Freq,A] = RetrieveFOinfo(app,EL);
            
            if ~isempty(DesiredMode)
                KeepIdx = (str2double(DesiredMode.LowF)-0.1 < Freq) & (Freq < str2double(DesiredMode.HighF)+0.01);
                St = St(KeepIdx);
                En = En(KeepIdx);
                Freq = Freq(KeepIdx);
                A = A(KeepIdx);
%             elseif ~isempty(Freq)
%                 DesiredMode.LowF = num2str(min(Freq));
%                 DesiredMode.HighF = num2str(max(Freq));
            end
            
            
            fOOR = fill(PlotPointer,tStart,1,'b','FaceAlpha',0.3,'EdgeColor','b','LineWidth',3);
            hold(PlotPointer);
            fRING = fill(PlotPointer,tStart,1,'g','FaceAlpha',0.3,'EdgeColor','g','LineWidth',3);
            fFObk = plot(PlotPointer,tStart,1,'k','LineWidth',2);
            fFOred = plot(PlotPointer,tStart,1,'r','LineWidth',3);
            
            if ~isempty(St)
                MinF = min(Freq);
                MaxF = max(Freq);
                for Eidx = 1:length(St)
                    tPlot = [St(Eidx) En(Eidx)];
                    tPlot.TimeZone = tStart.TimeZone;
                    
                    if A(Eidx) == 1
                        clr = 'r';
                        lwdth = 3;
                    else
                        clr = 'k';
                        lwdth = 2;
                    end
                    
                    plot(PlotPointer,tPlot,Freq(Eidx)*ones(1,2),clr,'LineWidth',lwdth);
                    
%                     if Eidx == 1
%                         hold(PlotPointer);
%                     end
                end
                xlim(PlotPointer,[tStart tNow]);
%                 ylim(PlotPointer,[max(str2double(DesiredMode.LowF)-0.1, 0) str2double(DesiredMode.HighF)+0.1]);
            else
                plot(PlotPointer,tStart,NaN)
%                 if ~isempty(DesiredMode)
%                     ylim(PlotPointer,[max(str2double(DesiredMode.LowF)-0.1, 0) str2double(DesiredMode.HighF)+0.1]);
%                 end
                xlim(PlotPointer,[tStart tNow]);
%                 hold(PlotPointer);
            end
            if ~isempty(DesiredMode)
                ylim(PlotPointer,[max(str2double(DesiredMode.LowF)-0.1, 0) str2double(DesiredMode.HighF)+0.1]);
            end
            
            EL = LoadEventLists(app,tStart,tNow,EventPath,'OutOfRangeGeneral');
            AddEventsToALLplot(app,tStart,EL,PlotPointer,'b');
            
            EL = LoadEventLists(app,tStart,tNow,EventPath,'Ringdown');
            AddEventsToALLplot(app,tStart,EL,PlotPointer,'g');
            
            legend(PlotPointer,[fOOR fRING fFObk fFOred],{'Out-of-Range','Ringdown','FO','FO Alarm'},'location','northwest');
            
            hold(PlotPointer);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ProjectPathEditField
        function ProjectPathEditFieldValueChanged(app, event)
            ProjectPath = app.ProjectPathEditField.Value;
            [Projects, Tasks] = ListProjectsTasks(ProjectPath);
            app.ProjectDropDown.Items = Projects;
            
            SelectedProject = app.ProjectDropDown.Value;
            if ~isempty(SelectedProject)
                ProjectIdx = strcmp(Projects,SelectedProject);
                
                if length(Tasks{ProjectIdx}) == 1
                    Tasks{ProjectIdx} = ['Select' Tasks{ProjectIdx}];
                end
                app.TaskDropDown.Items = Tasks{ProjectIdx};
            else
                app.TaskDropDown.Items = {};
            end
        end

        % Value changed function: ProjectDropDown
        function ProjectDropDownValueChanged(app, event)
            ProjectPath = app.ProjectPathEditField.Value;
            [Projects, Tasks] = ListProjectsTasks(ProjectPath);
            
            SelectedProject = app.ProjectDropDown.Value;
            ProjectIdx = strcmp(Projects,SelectedProject);
            if length(Tasks{ProjectIdx}) == 1
                Tasks{ProjectIdx} = ['Select' Tasks{ProjectIdx}];
            end
            app.TaskDropDown.Items = Tasks{ProjectIdx};
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            runFlag = app.RunButton.Value;
            
            % How often to update
            UpdateInterval = 0.04;
            tElapseHist = UpdateInterval*ones(1,25);
            
            PlotClrs = [0.9290, 0.6940, 0.1250;...
                0, 0.4470, 0.7410;...
                0.8500, 0.3250, 0.0980;...
                0.4940, 0.1840, 0.5560;...
                0.4660, 0.6740, 0.1880;...
                0.3010, 0.7450, 0.9330;...
                0.6350, 0.0780, 0.1840;...
                0, 0.5, 0;...
                1, 0, 1;...
                0, 0.75, 0.75;...
                0.75, 0, 0.75;...
                0.75, 0.75, 0];
            
            if runFlag == 1
                ProjectPath = app.ProjectPathEditField.Value;                
                Project = ['Project_' app.ProjectDropDown.Value];
                Task = ['Task_' app.TaskDropDown.Value];
                
                InitPath = fullfile(ProjectPath,Project,Task,'Init');
                EventPath = fullfile(ProjectPath,Project,Task,'Event');
                
                if iscell(EventPath)
                    app.RunButton.Value = 0;
                    runFlag = 0;
                    msgbox('Please specify a project path, project, and task.');
                end
            end
            
            while runFlag == 1
                try
                    
                tic
                %
                % Get the current time
                if strcmp(app.TimeDropDown.Value,'UTC')
                    tNow = datetime('now','TimeZone','UTC');%-110.6478;
                else
                    tNow = datetime('now','TimeZone','local');%-110.6478;
                end
                
                % Get user input for number of minutes to display
                HistMin = str2double(app.WindowminDropDown.Value);
                % Number of minutes to display for mode meter
                HistMinMM = HistMin;
                % Number of minutes to display for out-of-range detector
                HistMinOOR = HistMin;
                % Number of minutes to display for ringdown detector
                HistMinRING = HistMin;
                % Number of minutes to display for forced oscillation detector
                HistMinFO = HistMin;
                % Number of minutes to display on the All Events tab
                HistMinALL = HistMin;
                % Number of minutes to display for the wind ramp detector
                HistMinWIND = HistMin;
                
                
                if strcmp(app.TabGroup.SelectedTab.Title,'Forced Oscillations')
                    % Start time of the window to be displayed
                    tStart = tNow - HistMinFO/60/24;
                    
                    EL = LoadEventLists(app,tStart,tNow,EventPath,'ForcedOscillation');
                    [St,En,Freq,A] = RetrieveFOinfo(app,EL);
                    
                    if ~isempty(St)
                        MinF = min(Freq);
                        MaxF = max(Freq);
                        for Eidx = 1:length(St)
                            tPlot = [St(Eidx) En(Eidx)];
                            tPlot.TimeZone = tStart.TimeZone;
                            
                            if A(Eidx) == 1
                                clr = 'r';
                                lwdth = 3;
                            else
                                clr = 'k';
                                lwdth = 2;
                            end
                            
                            plot(app.FOplot,tPlot,Freq(Eidx)*ones(1,2),clr,'LineWidth',lwdth);
                            
                            if Eidx == 1
                                hold(app.FOplot);
                            end
                        end
                        xlim(app.FOplot,[tStart tNow]);
%                         ylim(app.FOplot,[max([MinF-0.05*max([(MaxF-MinF) 1]) 0]) MaxF+0.05*max([(MaxF-MinF) 1])]);
                        hold(app.FOplot);
                    else
                        plot(app.FOplot,tStart,NaN)
                        xlim(app.FOplot,[tStart tNow]);
                    end
                elseif strcmp(app.TabGroup.SelectedTab.Title,'Out of Range Events')
                    % Start time of the window to be displayed
                    tStart = tNow - HistMinOOR/60/24;
                    
                    % Load event lists
                    EL = LoadEventLists(app,tStart,tNow,EventPath,'OutOfRangeGeneral');
                    
                    SparseStartTime = datestr(tStart,'mm/dd/yyyy HH:MM:SS');
                    SparseEndTime = datestr(tNow,'mm/dd/yyyy HH:MM:SS');
                    SparseOut = GetSparseData(SparseStartTime,SparseEndTime,InitPath,'OutOfRangeGeneral');
                    
                    % If the detector wasn't implemented jump to next iteration of the while loop
                    if isempty(SparseOut)
%                         runFlag = app.RunButton.Value;
                        runFlag = logical(0);
                        continue;
                    end
                    
                    switch app.OORtabs.SelectedTab.Title
                        case '1 Detector'
                            OORplots = {app.OORplot_T1P1};
                        case '2 Detectors'
                            OORplots = {app.OORplot_T2P1, app.OORplot_T2P2};
                        case '3 Detectors'
                            OORplots = {app.OORplot_T3P1, app.OORplot_T3P2, app.OORplot_T3P3};
                    end
                    
                    GenRingOORplots(app,OORplots,SparseOut,PlotClrs,EL,tStart,tNow)
                    
                elseif strcmp(app.TabGroup.SelectedTab.Title,'Ringdowns')
                    % Start time of the window to be displayed
                    tStart = tNow - HistMinRING/60/24;
                    
                    % Load event lists
                    EL = LoadEventLists(app,tStart,tNow,EventPath,'Ringdown');
                    
                    SparseStartTime = datestr(tStart,'mm/dd/yyyy HH:MM:SS');
                    SparseEndTime = datestr(tNow,'mm/dd/yyyy HH:MM:SS');
                    SparseOut = GetSparseData(SparseStartTime,SparseEndTime,InitPath,'Ringdown');
                    
                    % If the detector wasn't implemented jump to next iteration of the while loop
                    if isempty(SparseOut)
%                         runFlag = app.RunButton.Value;
                        runFlag = logical(0);
                        continue;
                    end
                    
                    switch app.RINGtabs.SelectedTab.Title
                        case '1 Detector'
                            RINGplots = {app.RINGplot_T1P1};
                        case '2 Detectors'
                            RINGplots = {app.RINGplot_T2P1, app.RINGplot_T2P2};
                        case '3 Detectors'
                            RINGplots = {app.RINGplot_T3P1, app.RINGplot_T3P2, app.RINGplot_T3P3};
                    end
                    
                    GenRingOORplots(app,RINGplots,SparseOut,PlotClrs,EL,tStart,tNow)
                    
                    
                elseif strcmp(app.TabGroup.SelectedTab.Title,'All Events')
                    PlotPointer = app.ALLplot;
                    
                    % Start time of the window to be displayed
                    tStart = tNow - HistMinALL/60/24;
                    
                    GenAllplot(app,tStart,tNow,EventPath,PlotPointer,{})
                    
                elseif strcmp(app.TabGroup.SelectedTab.Title,'Wind Ramps')
                    % Start time of the window to be displayed
                    tStart = tNow - HistMinWIND/60/24;
                    
                    % Load event lists
                    EL = LoadEventLists(app,tStart,tNow,EventPath,'WindRamp');
                    for Eidx = 1:length(EL)
                        EL(Eidx).Start = datenum(EL(Eidx).TrendStart);
                        EL(Eidx).End = datenum(EL(Eidx).TrendEnd);
                    end
                    
                    SparseStartTime = datestr(tStart,'mm/dd/yyyy HH:MM:SS');
                    SparseEndTime = datestr(tNow,'mm/dd/yyyy HH:MM:SS');
                    SparseOut = GetSparseData(SparseStartTime,SparseEndTime,InitPath,'WindRamp');
                    
                    % If the detector wasn't implemented jump to next iteration of the while loop
                    if isempty(SparseOut)
%                         runFlag = app.RunButton.Value;
                        runFlag = logical(0);
                        continue;
                    end
                    
                    switch app.WINDtabs.SelectedTab.Title
                        case '1 Detector'
                            WINDplots = {app.WINDplot_T1P1};
                        case '2 Detectors'
                            WINDplots = {app.WINDplot_T2P1, app.WINDplot_T2P2};
                        case '3 Detectors'
                            WINDplots = {app.WINDplot_T3P1, app.WINDplot_T3P2, app.WINDplot_T3P3};
                    end
                    
                    GenRingOORplots(app,WINDplots,SparseOut,PlotClrs,EL,tStart,tNow)
                elseif strcmp(app.TabGroup.SelectedTab.Title,'Mode Meter')
                    % Start time of the window to be displayed
                    tStart = tNow - HistMinMM/60/24;
                    
                    if ~isempty(app.ModeMeter1DropDown.Value)
                        MM1 = ['Event\MM\' app.ModeMeter1DropDown.Value];
                    else
                        MM1 = 'Event\MM\';
                    end
                    if ~isempty(app.ModeMeter2DropDown.Value)
                        MM2 = ['Event\MM\' app.ModeMeter2DropDown.Value];
                    else
                        MM2 = 'Event\MM\';
                    end
                    MMpath = cell(1,2);
                    MMpath{1} = fullfile(ProjectPath,Project,Task,MM1);
                    MMpath{2} = fullfile(ProjectPath,Project,Task,MM2);
                    
                    
                    ModeMeterSelect = {app.ModeMeter1DropDown.Value, app.ModeMeter2DropDown.Value};
                    ModeSelect = {app.Mode1DropDown.Value, app.Mode2DropDown.Value};
                    AlgSelect = {app.Algorithm1DropDown.Value, app.Algorithm2DropDown.Value};
                    
                    Freq = cell(1,2);
                    Damp = cell(1,2);
                    t = cell(1,2);
                    Sigs = cell(1,2);
                    DesiredMode = cell(1,2);
                    
                    % For each of the mode meters that can be displayed
                    for Midx = 1:2
                        % If the start time and the current time are on different days, the previous day's result file needs to be loaded
                        if day(tStart) ~= day(tNow)
                            % Load yesterday's result file
                            ResPath = fullfile(MMpath{Midx},[datestr(tStart,'yymmdd') '.csv']);
                            if exist(ResPath,'file')
                                Tyesterday = readtable(ResPath,'HeaderLines',4);
                                % Subtract one day from time stamps
                                Tyesterday{:,1} = Tyesterday{:,1} - 24;
                            end
                        else
                            % No need for yesterday's result file
                            Tyesterday = [];
                        end
                        
                        % Read the result file for right now
                        ResPath = fullfile(MMpath{Midx},[datestr(tNow,'yymmdd') '.csv']);
                        
                        % If this file doesn't exist, jump to the next index
                        if ~exist(ResPath,'file')
                            runFlag = app.RunButton.Value;
                            continue
                        end
                        
                        H = readtable(ResPath,'ReadVariableNames',0);
                        H = H{1:4,:};
                        T = readtable(ResPath,'HeaderLines',4);
                        
                        % Add on yesterday's data
                        T = [Tyesterday; T];
                        
                        % Find the columns of damping measurements corresponding to the user's selections
                        try
                            Dcols = contains(H(1,:),'DampingRatio') & strcmp(H(2,:),ModeSelect{Midx}) & strcmp(H(4,:),AlgSelect{Midx});
                            Fcols = contains(H(1,:),'Frequency') & strcmp(H(2,:),ModeSelect{Midx}) & strcmp(H(4,:),AlgSelect{Midx});
                        catch
                            Dcols = [];
                            Fcols = [];
                        end
                        
                        t{Midx} = dateshift(tNow,'start','day') + T{:,1}/24;
                        Damp{Midx} = T{:,Dcols}*100;
                        Freq{Midx} = T{:,Fcols};
                        Sigs{Midx} = H(3,Dcols);
                        
                        DesiredMode{Midx} = RetrieveDesiredMode(app,ModeMeterSelect{Midx},ModeSelect{Midx});
                        
                        
                    end
                    
                    % Event plots at bottom of page
                    GenAllplot(app,tStart,tNow,EventPath,app.MMallPlot1,DesiredMode{1});
                    GenAllplot(app,tStart,tNow,EventPath,app.MMallPlot2,DesiredMode{2});
                    
                    
                    if ~isempty(Damp{1})
                        plot(app.Damp1,[tStart tNow],[3 3],'r','LineWidth',2);
                        hold(app.Damp1);
%                         plot(app.Damp1,[tStart tNow],[5 5],'Color',[0.9100 0.4100 0.1700],'LineWidth',2);
                        plot(app.Damp1,[tStart tNow],[5 5],'r:','LineWidth',2);
                        pH = zeros(1,size(Damp{1},2));
                        for k = 1:size(Damp{1},2)
                            ClrIdx = 1+mod(k,size(PlotClrs,1));
                            pH(k) = plot(app.Damp1,t{1},Damp{1}(:,k),'Color',PlotClrs(ClrIdx,:),'LineWidth',2);
                        end
                        hold(app.Damp1);
                        ylim(app.Damp1,[0 str2double(DesiredMode{1}.DampMax)]);
                        legend(app.Damp1,pH,Sigs{1},'Location','northwest','interpreter','none');
                    else
                        plot(app.Damp1,tStart,NaN);
                    end
                    xlim(app.Damp1,[tStart tNow]);
                    
                    if ~isempty(Damp{2})
                        plot(app.Damp2,[tStart tNow],[3 3],'r','LineWidth',2);
                        hold(app.Damp2);
%                         plot(app.Damp2,[tStart tNow],[5 5],'Color',[0.9100 0.4100 0.1700],'LineWidth',2);
                        plot(app.Damp2,[tStart tNow],[5 5],'r:','LineWidth',2);
                        pH = zeros(1,size(Damp{2},2));
                        for k = 1:size(Damp{2},2)
                            ClrIdx = 1+mod(k,size(PlotClrs,1));
                            pH(k) = plot(app.Damp2,t{2},Damp{2}(:,k),'Color',PlotClrs(ClrIdx,:),'LineWidth',2);
                        end
                        hold(app.Damp2);
                        ylim(app.Damp2,[0 str2double(DesiredMode{2}.DampMax)]);
                        legend(app.Damp2,pH,Sigs{2},'Location','northwest','interpreter','none');
                    else
                        plot(app.Damp2,tStart,NaN);
                    end
                    xlim(app.Damp2,[tStart tNow]); 
                       
                    if ~isempty(Freq{1})
                        for k = 1:size(Freq{1},2)
                            ClrIdx = 1+mod(k,size(PlotClrs,1));
                            plot(app.Freq1,t{1},Freq{1}(:,k),'Color',PlotClrs(ClrIdx,:),'LineWidth',2);
                            
                            if k == 1
                                hold(app.Freq1);
                            end
                        end
                        hold(app.Freq1);
                        ylim(app.Freq1,[str2double(DesiredMode{1}.LowF) str2double(DesiredMode{1}.HighF)]);
                        legend(app.Freq1,Sigs{1},'Location','northwest','interpreter','none');
                        
                        % Event plots at bottom of page
%                         ylim(app.MMallPlot1,[max(str2double(DesiredMode{1}.LowF)-0.1, 0) str2double(DesiredMode{1}.HighF)+0.01]);
                    else
                        plot(app.Freq1,tStart,NaN);
                    end
                    xlim(app.Freq1,[tStart tNow]);
                       
                    if ~isempty(Freq{2})
                        for k = 1:size(Freq{2},2)
                            ClrIdx = 1+mod(k,size(PlotClrs,1));
                            plot(app.Freq2,t{2},Freq{2}(:,k),'Color',PlotClrs(ClrIdx,:),'LineWidth',2);
                            
                            if k == 1
                                hold(app.Freq2);
                            end
                        end
                        hold(app.Freq2);
                        ylim(app.Freq2,[str2double(DesiredMode{2}.LowF) str2double(DesiredMode{2}.HighF)]);
                        legend(app.Freq2,Sigs{2},'Location','northwest','interpreter','none');
                        
                        % Event plots at bottom of page
%                         ylim(app.MMallPlot2,[max(str2double(DesiredMode{2}.LowF)-0.1, 0) str2double(DesiredMode{2}.HighF)+0.01]);
                    else
                        plot(app.Freq2,tStart,NaN);
                    end
                    xlim(app.Freq2,[tStart tNow]); 
                end
                
                drawnow;
                
                tElapse = toc;
                %
                % Based on the elapsed time of the main part of this function, pause the remainder of the UpdateInterval
                % This keeps the update inteval smooth, even if the time to execute the upper part of the while loop takes varying amounts of time
                pause(UpdateInterval-tElapse)
                %
                % Adjust UpdateInterval to be as small as possible
                tElapseHist = [tElapseHist(2:end) tElapse];
                UpdateInterval = ceil(mean(tElapseHist));
                
                
                runFlag = app.RunButton.Value;
                
                catch e
                    % Catch-all for unforseen errors that pop up. Prevents a hard close.
                    app.RunButton.Value = 0;
                    runFlag = 0;
                    msgbox(['Something went wrong: ' e.message]);
                end
            end
            app.RunButton.Value = logical(0);
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            ProjectPath = app.ProjectPathEditField.Value;
            Project = ['Project_' app.ProjectDropDown.Value];
            Task = ['Task_' app.TaskDropDown.Value];
            
            ConfigFile = fullfile(ProjectPath,Project,Task,'Config.xml');
            
            try
                ConfigAll = fun_xmlread_comments(ConfigFile);
                DetectorXML = ConfigAll.Config.DetectorConfig.Configuration;
                if isfield(DetectorXML,'ModeMeter')
                    if length(DetectorXML.ModeMeter) == 1
                        MMconfig = {DetectorXML.ModeMeter};
                    else
                        MMconfig = DetectorXML.ModeMeter;
                    end
                    
                    MMlist = cell(1,length(MMconfig));
                    for MMidx = 1:length(MMconfig)
                        MMlist{MMidx} = MMconfig{MMidx}.ResultPath(5:end);
                    end
                    
                    if length(MMlist) == 1
                        MMlist = ['Select' MMlist];
                    end
                    
                    app.ModeMeter1DropDown.Items = MMlist;
                    app.ModeMeter2DropDown.Items = MMlist;
                else
                    app.ModeMeter1DropDown.Items = {'No Mode Meters'};
                    app.ModeMeter2DropDown.Items = {'No Mode Meters'};
                end
            catch
                app.ModeMeter1DropDown.Items = {'Invalid Project Path'};
                app.ModeMeter2DropDown.Items = {'Invalid Project Path'};
            end
        end

        % Value changed function: ModeMeter1DropDown
        function ModeMeter1DropDownValueChanged(app, event)
            MMselection = app.ModeMeter1DropDown.Value;
            ModeList = RetrieveModeList(app,MMselection);
            ModeList = ['Select' ModeList];
            
            app.Mode1DropDown.Items = ModeList;
        end

        % Value changed function: ModeMeter2DropDown
        function ModeMeter2DropDownValueChanged(app, event)
            MMselection = app.ModeMeter2DropDown.Value;
            ModeList = RetrieveModeList(app,MMselection);
            
            app.Mode2DropDown.Items = ModeList;
        end

        % Value changed function: Mode1DropDown
        function Mode1DropDownValueChanged(app, event)
            ModeSelection = app.Mode1DropDown.Value;
            MMselection = app.ModeMeter1DropDown.Value;
            AlgList = RetrieveAlgList(app,MMselection,ModeSelection);
            
            app.Algorithm1DropDown.Items = AlgList;
        end

        % Value changed function: Mode2DropDown
        function Mode2DropDownValueChanged(app, event)
            ModeSelection = app.Mode2DropDown.Value;
            MMselection = app.ModeMeter2DropDown.Value;
            AlgList = RetrieveAlgList(app,MMselection,ModeSelection);
            
            app.Algorithm2DropDown.Items = AlgList;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ProjectPathEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectPathEditFieldLabel.Text = 'Project Path';
            app.ProjectPathEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectPathEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectPathEditFieldValueChanged, true);
            app.ProjectDropDownLabel = uilabel(app.UIFigure);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDown.Items = {};
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDropDownValueChanged, true);
            app.ProjectDropDown.Value = {};
            app.TaskDropDownLabel = uilabel(app.UIFigure);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.UIFigure);
            app.TaskDropDown.Items = {};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Value = {};
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ForcedOscillationsTab = uitab(app.TabGroup);
            app.ForcedOscillationsTab.Title = 'Forced Oscillations';
            app.FOplot = uiaxes(app.ForcedOscillationsTab);
            ylabel(app.FOplot, 'Oscillation Frequency (Hz)')
            app.OutofRangeEventsTab = uitab(app.TabGroup);
            app.OutofRangeEventsTab.Title = 'Out of Range Events';
            app.OORtabs = uitabgroup(app.OutofRangeEventsTab);
            app.OORtab1 = uitab(app.OORtabs);
            app.OORtab1.Title = '1 Detector';
            app.OORplot_T1P1 = uiaxes(app.OORtab1);
            app.OORtab2 = uitab(app.OORtabs);
            app.OORtab2.Title = '2 Detectors';
            app.OORplot_T2P1 = uiaxes(app.OORtab2);
            app.OORplot_T2P2 = uiaxes(app.OORtab2);
            app.OORtab3 = uitab(app.OORtabs);
            app.OORtab3.Title = '3 Detectors';
            app.OORplot_T3P1 = uiaxes(app.OORtab3);
            app.OORplot_T3P2 = uiaxes(app.OORtab3);
            app.OORplot_T3P3 = uiaxes(app.OORtab3);
            app.RingdownsTab = uitab(app.TabGroup);
            app.RingdownsTab.Title = 'Ringdowns';
            app.RINGtabs = uitabgroup(app.RingdownsTab);
            app.RINGtab1 = uitab(app.RINGtabs);
            app.RINGtab1.Title = '1 Detector';
            app.RINGplot_T1P1 = uiaxes(app.RINGtab1);
            app.DetectorsTab2 = uitab(app.RINGtabs);
            app.DetectorsTab2.Title = '2 Detectors';
            app.RINGplot_T2P1 = uiaxes(app.DetectorsTab2);
            app.RINGplot_T2P2 = uiaxes(app.DetectorsTab2);
            app.RINGtab3 = uitab(app.RINGtabs);
            app.RINGtab3.Title = '3 Detectors';
            app.RINGplot_T3P1 = uiaxes(app.RINGtab3);
            app.RINGplot_T3P2 = uiaxes(app.RINGtab3);
            app.RINGplot_T3P3 = uiaxes(app.RINGtab3);
            app.AllEventsTab = uitab(app.TabGroup);
            app.AllEventsTab.Title = 'All Events';
            app.ALLplot = uiaxes(app.AllEventsTab);
            ylabel(app.ALLplot, 'Oscillation Frequency (Hz)')
            app.WindRampsTab = uitab(app.TabGroup);
            app.WindRampsTab.Title = 'Wind Ramps';
            app.WINDtabs = uitabgroup(app.WindRampsTab);
            app.WINDtab1 = uitab(app.WINDtabs);
            app.WINDtab1.Title = '1 Detector';
            app.WINDplot_T1P1 = uiaxes(app.WINDtab1);
            app.WINDtab2 = uitab(app.WINDtabs);
            app.WINDtab2.Title = '2 Detectors';
            app.WINDplot_T2P1 = uiaxes(app.WINDtab2);
            app.WINDplot_T2P2 = uiaxes(app.WINDtab2);
            app.WINDtab3 = uitab(app.WINDtabs);
            app.WINDtab3.Title = '3 Detectors';
            app.WINDplot_T3P1 = uiaxes(app.WINDtab3);
            app.WINDplot_T3P2 = uiaxes(app.WINDtab3);
            app.WINDplot_T3P3 = uiaxes(app.WINDtab3);
            app.ModeMeterTab = uitab(app.TabGroup);
            app.ModeMeterTab.Title = 'Mode Meter';
            app.Mode2DropDownLabel = uilabel(app.ModeMeterTab);
            app.Mode2DropDownLabel.Text = 'Mode 2';
            app.Mode2DropDown = uidropdown(app.ModeMeterTab);
            app.Mode2DropDown.Items = {};
            app.Mode2DropDown.ValueChangedFcn = createCallbackFcn(app, @Mode2DropDownValueChanged, true);
            app.Mode2DropDown.Value = {};
            app.ModeMeter1DropDownLabel = uilabel(app.ModeMeterTab);
            app.ModeMeter1DropDownLabel.Text = 'Mode Meter 1';
            app.ModeMeter1DropDown = uidropdown(app.ModeMeterTab);
            app.ModeMeter1DropDown.Items = {};
            app.ModeMeter1DropDown.ValueChangedFcn = createCallbackFcn(app, @ModeMeter1DropDownValueChanged, true);
            app.ModeMeter1DropDown.Value = {};
            app.ModeMeter2DropDownLabel = uilabel(app.ModeMeterTab);
            app.ModeMeter2DropDownLabel.Text = 'Mode Meter 2';
            app.ModeMeter2DropDown = uidropdown(app.ModeMeterTab);
            app.ModeMeter2DropDown.Items = {};
            app.ModeMeter2DropDown.ValueChangedFcn = createCallbackFcn(app, @ModeMeter2DropDownValueChanged, true);
            app.ModeMeter2DropDown.Value = {};
            app.Algorithm1DropDownLabel = uilabel(app.ModeMeterTab);
            app.Algorithm1DropDownLabel.Text = 'Algorithm 1';
            app.Algorithm1DropDown = uidropdown(app.ModeMeterTab);
            app.Algorithm1DropDown.Items = {};
            app.Algorithm1DropDown.Value = {};
            app.Algorithm2DropDownLabel = uilabel(app.ModeMeterTab);
            app.Algorithm2DropDownLabel.Text = 'Algorithm 2';
            app.Algorithm2DropDown = uidropdown(app.ModeMeterTab);
            app.Algorithm2DropDown.Items = {};
            app.Algorithm2DropDown.Value = {};
            app.Mode1DropDownLabel = uilabel(app.ModeMeterTab);
            app.Mode1DropDownLabel.Text = 'Mode 1';
            app.Mode1DropDown = uidropdown(app.ModeMeterTab);
            app.Mode1DropDown.Items = {};
            app.Mode1DropDown.ValueChangedFcn = createCallbackFcn(app, @Mode1DropDownValueChanged, true);
            app.Mode1DropDown.Value = {};
            app.Damp1 = uiaxes(app.ModeMeterTab);
            ylabel(app.Damp1, 'Damping Ratio (%)')
            app.Freq1 = uiaxes(app.ModeMeterTab);
            ylabel(app.Freq1, 'Frequency (Hz)')
            app.Damp2 = uiaxes(app.ModeMeterTab);
            ylabel(app.Damp2, 'Damping Ratio (%)')
            app.Freq2 = uiaxes(app.ModeMeterTab);
            ylabel(app.Freq2, 'Frequency (Hz)')
            app.MMallPlot1 = uiaxes(app.ModeMeterTab);
            ylabel(app.MMallPlot1, 'Oscillation Frequency (Hz)')
            app.MMallPlot2 = uiaxes(app.ModeMeterTab);
            ylabel(app.MMallPlot2, 'Oscillation Frequency (Hz)')
            app.WindowminDropDownLabel = uilabel(app.UIFigure);
            app.WindowminDropDownLabel.Text = 'Window (min)';
            app.WindowminDropDown = uidropdown(app.UIFigure);
            app.WindowminDropDown.Items = {'5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60'};
            app.WindowminDropDown.Value = '20';
            app.TimeDropDownLabel = uilabel(app.UIFigure);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.UIFigure);
            app.TimeDropDown.Items = {'UTC', 'Local'};
            app.TimeDropDown.Value = 'UTC';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ArchiveWalkerRTD

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end