classdef EventDataMover < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ProjectPathEditFieldLabel  matlab.ui.control.Label
        ProjectPathEditField       matlab.ui.control.EditField
        ProjectDropDownLabel       matlab.ui.control.Label
        ProjectDropDown            matlab.ui.control.DropDown
        TaskDropDownLabel          matlab.ui.control.Label
        TaskDropDown               matlab.ui.control.DropDown
        OutputPathEditFieldLabel   matlab.ui.control.Label
        OutputPathEditField        matlab.ui.control.EditField
        StartTimeEditFieldLabel    matlab.ui.control.Label
        StartTimeEditField         matlab.ui.control.EditField
        EndTimeEditFieldLabel      matlab.ui.control.Label
        EndTimeEditField           matlab.ui.control.EditField
        BatchButton                matlab.ui.control.Button
        yyyymmddHHMMSSLabel        matlab.ui.control.Label
        yyyymmddHHMMSSLabel_2      matlab.ui.control.Label
        DailyExportButton          matlab.ui.control.StateButton
        SurroundingDataminutesEditFieldLabel  matlab.ui.control.Label
        SurroundingDataminutesEditField  matlab.ui.control.NumericEditField
        LampLabel                  matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        AbortButton                matlab.ui.control.StateButton
    end


    methods (Access = private)
        
        function EL = LoadEventLists(app,tStart,tEnd,EventPath,Detector)
            if exist(fullfile(EventPath,'EventList_Current.XML'),'file')
                EL = EventListXML2MAT(fun_xmlread_comments(fullfile(EventPath,'EventList_Current.XML')));
                % Set to empty if the file was completely empty or if there were no Out-of-Range events
                if isstruct(EL)
                    if isfield(EL,Detector)
                        EL = EL.(Detector);
                    else
                        EL = [];
                    end
                else
                    EL = [];
                end
            else
                EL = [];
            end
            
            % For each of the days in the specified range
            % Load the event list and add it
            for t = dateshift(tStart,'start','day'):dateshift(tEnd,'start','day')
                if exist(fullfile(EventPath,['EventList_' datestr(t,'yymmdd') '.XML']),'file')
                    ELday = EventListXML2MAT(fun_xmlread_comments(fullfile(EventPath,['EventList_' datestr(t,'yymmdd') '.XML'])));
                    % Set to empty if the file was completely empty or if there were no Out-of-Range events
                    if isstruct(ELday)
                        if isfield(ELday,Detector)
                            ELday = ELday.(Detector);
                        else
                            ELday = [];
                        end
                    else
                        ELday = [];
                    end
                else
                    ELday = [];
                end
                
                EL = [EL ELday];
            end
            
            % If any events were found, capture their ends
            if isstruct(EL)
                Estarts = [EL.Start];
                Eends = [EL.End];
            else
                Estarts = [];
                Eends = [];
            end
            % Remove those ending before start time or starting after the end time
            KillIdx = (Eends < datenum(tStart)) | (Estarts > datenum(tEnd));
            EL(KillIdx) = [];
        end
    
        function ExtractedParameters = ExtractParameters(app,Parameters)
    
            % Path to save output files
            if isfield(Parameters,'SavePath')
                SavePath = Parameters.SavePath;
                if exist(SavePath,'dir') == 0
                    error(['Save path ' SavePath ' does not exist.']);
                end
            else
                error(['Save path ' SavePath ' does not exist.']);
            end
            
            % Flag indicating whether each PMU should be stored in its own folder
            if isfield(Parameters,'SeparatePMUs')
                if strcmp('TRUE',Parameters.SeparatePMUs)
                    SeparatePMUs = true;
                else
                    SeparatePMUs = false;
                end
            else
                SeparatePMUs = false;
            end
            
            if SeparatePMUs
                % PMUs are to be placed in separate folders. The PMU name is used as
                % the mnemonic for file naming
                Mnemonic = '';
            else
                % PMUs are all going in one folder. If the user specified a mnemonic,
                % use it. Otherwise, use the generic 'ExportedData'
                if isfield(Parameters,'Mnemonic')
                    Mnemonic = Parameters.Mnemonic;
                else
                    Mnemonic = 'ExportedData';
                end
            end
            
            ExtractedParameters = struct('SavePath',SavePath,...
                'SeparatePMUs',SeparatePMUs,'Mnemonic',Mnemonic);
        end
    
    
        function NumEvents = ExportEvents(app,StartTime,EndTime)
            NumEvents = NaN;
            
            TaskPath = fullfile(app.ProjectPathEditField.Value,['Project_' app.ProjectDropDown.Value],['Task_' app.TaskDropDown.Value]);
            
            if iscell(TaskPath)
                msgbox('Project and task are not specified properly','Error');
                return;
            end
            
            ConfigFile = fullfile(TaskPath,'Config.xml');
            if exist(ConfigFile,'file') == 0
                msgbox(['Configuration file could not be found at ' TaskPath],'Error');
                return;
            end
            
            ConfigAll = fun_xmlread_comments(ConfigFile);
            DetectorXML = ConfigAll.Config.DetectorConfig.Configuration;
            if isfield(DetectorXML,'DataWriter')
                DW = DetectorXML.DataWriter;
                if length(DW) == 1
                    DW = {DW};
                end
                
                % Extract the parameters for each data writer
                DWparams = cell(1,length(DW));
                for DWidx = 1:length(DW)
                    DWparams{DWidx} = ExtractParameters(app,DW{DWidx});
                end
            else
                msgbox('This task did not include a data writer.','Error');
                return;
            end

            
            EventPath = fullfile(TaskPath,'Event');
            if exist(EventPath,'dir') == 0
                msgbox(['Event folder could not be found at ' TaskPath],'Error');
                return;
            end
            
            OutputPath = app.OutputPathEditField.Value;
            if exist(OutputPath,'dir') == 0
                msgbox('Specified output path does not exist','Error');
                return;
            end
            % Create Ringdown and OutOfRange folders in the output directory if they don't exist
            DetNames = {'Ringdown','OutOfRange'};
            for DetIdx = 1:length(DetNames)
                if exist(fullfile(OutputPath,DetNames{DetIdx}),'dir') == 0
                    try
                        mkdir(fullfile(OutputPath,DetNames{DetIdx}))
                    catch
                        msgbox('Unable to create subfolders for output data.','Error');
                    end
                end
            end
            
            
            %**************************
            
            ELring = LoadEventLists(app,StartTime,EndTime,EventPath,'Ringdown');
            ELoor = LoadEventLists(app,StartTime,EndTime,EventPath,'OutOfRangeGeneral');
            
            NumEvents = length(ELring) + length(ELoor);
            
            EL = {ELring,ELoor};
            
            % For each detector go through events
            for DetIdx = 1:length(DetNames)
                if isempty(EL{DetIdx})
                    % No events detected by this detector, skip to next
                    continue;
                end
                
                % For each event
                for Eidx = 1:length(EL{DetIdx})
                    Eid = strrep(EL{DetIdx}(Eidx).ID,'.','_');
                    Estart = datetime(EL{DetIdx}(Eidx).Start,'ConvertFrom','datenum');
                    Eend = datetime(EL{DetIdx}(Eidx).End,'ConvertFrom','datenum');
                    
                    % Add minutes before and after the event based on the user input
                    Estart = Estart - app.SurroundingDataminutesEditField.Value/1440;
                    Eend = Eend + app.SurroundingDataminutesEditField.Value/1440;
                    
                    % For each data writer
                    for DWidx = 1:length(DWparams)
                        if DWparams{DWidx}.SeparatePMUs
                            DWstorage = dir(DWparams{DWidx}.SavePath);
                            DWstorage = {DWstorage.name};
                            
                            % Remove the non-PMU directories
                            DWstorage(strcmp(DWstorage,'.')) = [];
                            DWstorage(strcmp(DWstorage,'..')) = [];
                        else
                            % All PMUs placed together in the main folder
                            DWstorage = {[]};
                        end
                        
                        % For each folder containing data (could be just main folder or several PMU folders)
                        for StorageIdx = 1:length(DWstorage)
                            DWstorageFull = fullfile(DWparams{DWidx}.SavePath,DWstorage{StorageIdx});
                            
                            % Get a list of the full filepaths for every day between Estart and Eend
                            FilePaths = {};
                            FileNames = {};
                            FileTimes = [];
                            for t = dateshift(Estart,'start','day'):dateshift(Eend,'start','day')
                                yyyy = datestr(t,'yyyy');
                                yymmdd = datestr(t,'yymmdd');
                                FileListDay = dir(fullfile(DWstorageFull,yyyy,yymmdd));
                                FileListDay = FileListDay([FileListDay.isdir] == 0);
                                
                                FileListDayName = {FileListDay.name};
                                FileListDayFolder = {FileListDay.folder};
                                
                                FileNames = [FileNames FileListDayName];
                                
                                FileListDayPaths = strcat(FileListDayFolder,'\',FileListDayName);
                                FilePaths = [FilePaths FileListDayPaths];
                                
                                FileListDayTimes = FileListDayName;
                                StrStart = FileListDayTimes{1}(1:end-19);
                                FileListDayTimes = extractBetween(FileListDayTimes,StrStart,'.csv');
                                FileListDayTimes = datetime(FileListDayTimes,'InputFormat','yyyyMMdd_HHmmSS');
                                FileTimes = [FileTimes FileListDayTimes];
                            end
                            
                            KeepIdx = find(FileTimes<Estart,1,'last'):find(FileTimes<Eend,1,'last');
                            FilePaths = FilePaths(KeepIdx);
                            FileNames = FileNames(KeepIdx);
                            
                            NewPath = fullfile(OutputPath,DetNames{DetIdx},Eid,['DataWriter_' num2str(DWidx)],DWstorage{StorageIdx});
                            if exist(NewPath,'dir') == 0
                                mkdir(NewPath);
                            end
                            for FileIdx = 1:length(FilePaths)
                                NewFile = fullfile(NewPath,FileNames{FileIdx});
                                copyfile(FilePaths{FileIdx},NewFile);
                            end
                        end
                    end
                end
            end
            
            for k = 1:1000000000
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ProjectPathEditField
        function ProjectPathEditFieldValueChanged(app, event)
            ProjectPath = app.ProjectPathEditField.Value;
            [Projects, Tasks] = ListProjectsTasks(ProjectPath);
            app.ProjectDropDown.Items = Projects;
            
            SelectedProject = app.ProjectDropDown.Value;
            if ~isempty(SelectedProject)
                ProjectIdx = strcmp(Projects,SelectedProject);
                
                if length(Tasks{ProjectIdx}) == 1
                    Tasks{ProjectIdx} = ['Select' Tasks{ProjectIdx}];
                end
                app.TaskDropDown.Items = Tasks{ProjectIdx};
            else
                app.TaskDropDown.Items = {};
            end
        end

        % Value changed function: ProjectDropDown
        function ProjectDropDownValueChanged(app, event)
            ProjectPath = app.ProjectPathEditField.Value;
            [Projects, Tasks] = ListProjectsTasks(ProjectPath);
            
            SelectedProject = app.ProjectDropDown.Value;
            ProjectIdx = strcmp(Projects,SelectedProject);
            if length(Tasks{ProjectIdx}) == 1
                Tasks{ProjectIdx} = ['Select' Tasks{ProjectIdx}];
            end
            app.TaskDropDown.Items = Tasks{ProjectIdx};
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)

        end

        % Callback function
        function ModeMeter1DropDownValueChanged(app, event)
           
        end

        % Callback function
        function ModeMeter2DropDownValueChanged(app, event)
           
        end

        % Callback function
        function Mode1DropDownValueChanged(app, event)
            
        end

        % Callback function
        function Mode2DropDownValueChanged(app, event)
           
        end

        % Button pushed function: BatchButton
        function BatchButtonPushed(app, event)
            
            try
                StartTime = datetime(app.StartTimeEditField.Value,'InputFormat','yyyy-MM-dd HH:mm:ss');
                EndTime = datetime(app.EndTimeEditField.Value,'InputFormat','yyyy-MM-dd HH:mm:ss');
            catch
                msgbox('Enter times as yyyy-mm-dd HH:MM:SS','Error');
                return
            end
            
            app.Lamp.Color = [0 1 0];
            drawnow
            NumEvents = ExportEvents(app,StartTime,EndTime);
            app.Lamp.Color = [0.8 0.8 0.8];
            
            if ~isnan(NumEvents)
                if NumEvents > 0
                    msgbox(['Successfully exported ' num2str(NumEvents) ' events.'],'Done');
                else
                    msgbox('There were no events to export.','Done');
                end
            end
        end

        % Value changed function: DailyExportButton
        function DailyExportButtonValueChanged(app, event)
            value = app.DailyExportButton.Value;
            
            LastTime = datetime('now','TimeZone','UTC') - 1;
            
            while value == 1
                app.Lamp.Color = [0 1 0];
                drawnow;
                
                ThisTime = datetime('now','TimeZone','UTC');
                
                if day(ThisTime) ~= day(LastTime)
                    StartTime = dateshift(LastTime,'start','day');
                    EndTime = dateshift(ThisTime,'start','day');
                    NumEvents = ExportEvents(app,StartTime,EndTime);
                    
                    if isnan(NumEvents)
                        app.DailyExportButton.Value = 0;
                    end
                else
%                     tmr = timer;
%                     tmr.StartDelay = 3;
%                     tmr.TimerFcn = @(~,~) disp('3 seconds have elapsed');
%                     start(tmr)
                end
                
                LastTime = ThisTime;
                value = app.DailyExportButton.Value;
                drawnow;
            end
            app.Lamp.Color = [0.8 0.8 0.8];
            drawnow;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ProjectPathEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectPathEditFieldLabel.Text = 'Project Path';
            app.ProjectPathEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectPathEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectPathEditFieldValueChanged, true);
            app.ProjectDropDownLabel = uilabel(app.UIFigure);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDown.Items = {};
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDropDownValueChanged, true);
            app.ProjectDropDown.Value = {};
            app.TaskDropDownLabel = uilabel(app.UIFigure);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.UIFigure);
            app.TaskDropDown.Items = {};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Value = {};
            app.OutputPathEditFieldLabel = uilabel(app.UIFigure);
            app.OutputPathEditFieldLabel.Text = 'Output Path';
            app.OutputPathEditField = uieditfield(app.UIFigure, 'text');
            app.OutputPathEditField.Value = 'C:\Users\foll154\Documents\ArchiveWalker\EventData';
            app.StartTimeEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.UIFigure, 'text');
            app.StartTimeEditField.Value = '2017-05-23 00:00:00';
            app.EndTimeEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.UIFigure, 'text');
            app.EndTimeEditField.Value = '2017-05-23 01:00:00';
            app.BatchButton = uibutton(app.UIFigure, 'push');
            app.BatchButton.ButtonPushedFcn = createCallbackFcn(app, @BatchButtonPushed, true);
            app.BatchButton.Text = 'Batch';
            app.yyyymmddHHMMSSLabel = uilabel(app.UIFigure);
            app.yyyymmddHHMMSSLabel.Text = 'yyyy-mm-dd HH:MM:SS';
            app.yyyymmddHHMMSSLabel_2 = uilabel(app.UIFigure);
            app.yyyymmddHHMMSSLabel_2.Text = 'yyyy-mm-dd HH:MM:SS';
            app.DailyExportButton = uibutton(app.UIFigure, 'state');
            app.DailyExportButton.ValueChangedFcn = createCallbackFcn(app, @DailyExportButtonValueChanged, true);
            app.DailyExportButton.Text = 'Daily Export';
            app.SurroundingDataminutesEditFieldLabel = uilabel(app.UIFigure);
            app.SurroundingDataminutesEditFieldLabel.Text = 'Surrounding Data (minutes)';
            app.SurroundingDataminutesEditField = uieditfield(app.UIFigure, 'numeric');
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.AbortButton = uibutton(app.UIFigure, 'state');
            app.AbortButton.Text = 'Abort';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EventDataMover

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end