classdef RMSthresholdApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ProjectDropDown               matlab.ui.control.DropDown
        TaskDropDown                  matlab.ui.control.DropDown
        ProjectPathEditFieldLabel     matlab.ui.control.Label
        ProjectPathEditField          matlab.ui.control.EditField
        GoButton                      matlab.ui.control.Button
        ProbabilityofFalseAlarmEditFieldLabel  matlab.ui.control.Label
        ProbabilityofFalseAlarmEditField  matlab.ui.control.NumericEditField
        TrimPercentageEditFieldLabel  matlab.ui.control.Label
        TrimPercentageEditField       matlab.ui.control.NumericEditField
        StartTimeEditFieldLabel       matlab.ui.control.Label
        StartTimeEditField            matlab.ui.control.EditField
        EndTimeEditFieldLabel         matlab.ui.control.Label
        EndTimeEditField              matlab.ui.control.EditField
        WindowSizeminutesEditFieldLabel  matlab.ui.control.Label
        WindowSizeminutesEditField    matlab.ui.control.NumericEditField
        UpdateminutesEditFieldLabel   matlab.ui.control.Label
        UpdateminutesEditField        matlab.ui.control.NumericEditField
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
        MedianThresholdLabel          matlab.ui.control.Label
        RunningLampLabel              matlab.ui.control.Label
        RunningLamp                   matlab.ui.control.Lamp
        SavePathEditFieldLabel        matlab.ui.control.Label
        SavePathEditField             matlab.ui.control.EditField
        SaveButton                    matlab.ui.control.Button
        InstructionsTextAreaLabel     matlab.ui.control.Label
        InstructionsTextArea          matlab.ui.control.TextArea
        ProjectLabel                  matlab.ui.control.Label
        TaskLabel                     matlab.ui.control.Label
    end


    properties (Access = private)
        AllThresh = [];
        ResultTable = [];
        dt = NaT(0);
    end


    methods (Access = private)

        % Value changed function: ProjectPathEditField
        function ProjectPathEditFieldValueChanged(app, event)
            ProjectPath = app.ProjectPathEditField.Value;
            [Projects, Tasks] = ListProjectsTasks(ProjectPath);
            app.ProjectDropDown.Items = Projects;
            
            SelectedProject = app.ProjectDropDown.Value;
            if ~isempty(SelectedProject)
                ProjectIdx = strcmp(Projects,SelectedProject);
                
                if length(Tasks{ProjectIdx}) == 1
                    Tasks{ProjectIdx} = ['Select' Tasks{ProjectIdx}];
                end
                app.TaskDropDown.Items = Tasks{ProjectIdx};
            else
                app.TaskDropDown.Items = {};
            end
        end

        % Value changed function: ProjectDropDown
        function ProjectDropDownValueChanged(app, event)
            ProjectPath = app.ProjectPathEditField.Value;
            [Projects, Tasks] = ListProjectsTasks(ProjectPath);
            
            SelectedProject = app.ProjectDropDown.Value;
            ProjectIdx = strcmp(Projects,SelectedProject);
            if length(Tasks{ProjectIdx}) == 1
                Tasks{ProjectIdx} = ['Select' Tasks{ProjectIdx}];
            end
            app.TaskDropDown.Items = Tasks{ProjectIdx};
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            try
                app.RunningLamp.Color = 'green';
                drawnow;
                
                ProjectPath = app.ProjectPathEditField.Value;
                Project = ['Project_' app.ProjectDropDown.Value];
                Task = ['Task_' app.TaskDropDown.Value];
                
                ConfigFile = fullfile(ProjectPath,Project,Task,'Config.xml');
                
                StartTime = datetime(app.StartTimeEditField.Value);
                EndTime = datetime(app.EndTimeEditField.Value);
                Pfa = app.ProbabilityofFalseAlarmEditField.Value;
                TrimPct = app.TrimPercentageEditField.Value;
                WindowSizeMin = app.WindowSizeminutesEditField.Value;
                WindowOverlapMin = app.UpdateminutesEditField.Value;
                
                [gam,app.dt,PMUlist,ChannelList,BandList] = SetRMSenergyThresh(ConfigFile,Pfa,TrimPct,StartTime,EndTime,WindowSizeMin,WindowOverlapMin);
    
                gamMedian = cellfun(@(x) median(x,'omitnan'),gam);
                
                app.ResultTable = table(PMUlist',ChannelList',BandList',gamMedian','VariableNames',{'PMU','Channel','Band','Threshold'});
                app.UITable.Data = table(PMUlist',ChannelList',BandList',num2str(gamMedian',8));
                
                app.AllThresh = [gam{:}];
                plot(app.UIAxes,app.dt,app.AllThresh)
                legend(app.UIAxes,ChannelList);
            catch e
                msgbox(e.message);
            end
            
            app.RunningLamp.Color = 'red';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                AllThresholds = app.AllThresh;
                AllThresholds = AllThresholds';
                TimeStamps = app.dt;
                FinalResults = app.ResultTable;
                save(app.SavePathEditField.Value,'AllThresholds','TimeStamps','FinalResults');
            catch e
                msgbox(e.message);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ProjectDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDown.Items = {};
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDropDownValueChanged, true);
            app.ProjectDropDown.Value = {};
            app.TaskDropDown = uidropdown(app.UIFigure);
            app.TaskDropDown.Items = {};
            app.TaskDropDown.Value = {};
            app.ProjectPathEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectPathEditFieldLabel.Text = 'Project Path';
            app.ProjectPathEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectPathEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectPathEditFieldValueChanged, true);
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ProbabilityofFalseAlarmEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilityofFalseAlarmEditFieldLabel.Text = 'Probability of False Alarm';
            app.ProbabilityofFalseAlarmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProbabilityofFalseAlarmEditField.Limits = [1e-11 1];
            app.ProbabilityofFalseAlarmEditField.Value = 0.0001;
            app.TrimPercentageEditFieldLabel = uilabel(app.UIFigure);
            app.TrimPercentageEditFieldLabel.Text = 'Trim Percentage';
            app.TrimPercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrimPercentageEditField.Limits = [0 99];
            app.TrimPercentageEditField.Value = 10;
            app.StartTimeEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.UIFigure, 'text');
            app.StartTimeEditField.Value = 'yyyy-mm-dd HH:MM';
            app.EndTimeEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.UIFigure, 'text');
            app.EndTimeEditField.Value = 'yyyy-mm-dd HH:MM';
            app.WindowSizeminutesEditFieldLabel = uilabel(app.UIFigure);
            app.WindowSizeminutesEditFieldLabel.Text = 'Window Size (minutes)';
            app.WindowSizeminutesEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowSizeminutesEditField.Limits = [0 120];
            app.WindowSizeminutesEditField.Value = 30;
            app.UpdateminutesEditFieldLabel = uilabel(app.UIFigure);
            app.UpdateminutesEditFieldLabel.Text = 'Update (minutes)';
            app.UpdateminutesEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateminutesEditField.Limits = [0 119];
            app.UpdateminutesEditField.Value = 15;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'PMU'; 'Channel'; 'Band'; 'Threshold'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false true];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Thresholds Over Time')
            ylabel(app.UIAxes, 'Energy')
            app.MedianThresholdLabel = uilabel(app.UIFigure);
            app.MedianThresholdLabel.Text = 'Median Threshold';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.SavePathEditFieldLabel = uilabel(app.UIFigure);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.UIFigure, 'text');
            app.SavePathEditField.Value = 'C:\Users\...\results.mat';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.InstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.UIFigure);
            app.InstructionsTextArea.Editable = 'off';
            app.InstructionsTextArea.Value = {'Using Archive Walker, set up a task that 1) calculates RMS-energy (accessed as a filter under the Signal Processing tab) and 2) stores the resulting signals using the Data Writer functionality. Enter the project path, which is displayed in the upper left corner of Archive Walker, in the Project Path field below. Then select the appropriate Project and Task from the dropdowns. Choose a start and end time within the time range of RMS-energy values that were calculated. When the Go button is clicked, this tool will read the RMS-energy values and estimate a detection threshold for each signal using Option 3 from the journal paper on thresholds for RMS-energy detectors. The input parameters for this option are the probability of false alarm and the amount of trimming desired in the trimmed mean and variance calculations (see the fields below). Thresholds will be generated using Window Size minutes of data every Update minutes (see the fields below). All resulting thresholds will be plotted. To account for varying system conditions, the medians for these thresholds are calculated and listed in the table below. To allow more detailed review, the results from the plot and table can be saved to a mat file using the Save button at the bottom of this window.'};
            app.ProjectLabel = uilabel(app.UIFigure);
            app.ProjectLabel.Text = 'Project';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Task';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RMSthresholdApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end